classdef Carp2Cartesian < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Carp2CartesianMeshConversionToolLabel  matlab.ui.control.Label
        ChooseLabelstoProcessPanel     matlab.ui.container.Panel
        FilterringStatusLamp           matlab.ui.control.Lamp
        FilterringStatusLampLabel      matlab.ui.control.Label
        AvilableTagsEditField          matlab.ui.control.EditField
        AvilableTagsEditFieldLabel     matlab.ui.control.Label
        FilterlabelsButton             matlab.ui.control.Button
        NameRegionEditField            matlab.ui.control.EditField
        NameRegionEditFieldLabel       matlab.ui.control.Label
        SelectedTagsEditField          matlab.ui.control.EditField
        SelectedLabelsEditFieldLabel   matlab.ui.control.Label
        LoadMeshPanel                  matlab.ui.container.Panel
        MeshLoadedLamp                 matlab.ui.control.Lamp
        SummaryLoadedLamp              matlab.ui.control.Lamp
        LoadMeshButton                 matlab.ui.control.Button
        CommentsTextArea               matlab.ui.control.TextArea
        CommentsTextAreaLabel          matlab.ui.control.Label
        OpenSummaryFileButton          matlab.ui.control.Button
        CalculatePrealphaShapePanel    matlab.ui.container.Panel
        DownsampleEditField            matlab.ui.control.NumericEditField
        DownsampleEditFieldLabel       matlab.ui.control.Label
        PrealphaShapestatusLamp        matlab.ui.control.Lamp
        PrealphaShapestatusLampLabel   matlab.ui.control.Label
        CalculateAlphaShapeButton      matlab.ui.control.Button
        AdjustAlphaButton              matlab.ui.control.Button
        HoleThresholdEditField         matlab.ui.control.NumericEditField
        HoleThresholdEditFieldLabel    matlab.ui.control.Label
        AdjustedAlphaEditField         matlab.ui.control.NumericEditField
        AdjustedAlphaEditFieldLabel    matlab.ui.control.Label
        CalculatedAlphaEditField       matlab.ui.control.NumericEditField
        CalculatedAlphaEditFieldLabel  matlab.ui.control.Label
        GenerateCartesianGridPanel     matlab.ui.container.Panel
        GenerateCompressandSaveButton  matlab.ui.control.Button
        SavetoDiskLamp                 matlab.ui.control.Lamp
        SavetoDiskLampLabel            matlab.ui.control.Label
        CreateAlphashapeLamp           matlab.ui.control.Lamp
        CreateAlphashapeLampLabel      matlab.ui.control.Label
        GenerateCompressLamp           matlab.ui.control.Lamp
        GenerateCompressLampLabel      matlab.ui.control.Label
        GridNameEditField              matlab.ui.control.EditField
        GridNameEditFieldLabel         matlab.ui.control.Label
        mySpinner                      matlab.ui.control.Spinner
        mySpinnerLabel                 matlab.ui.control.Label
        mxSpinner                      matlab.ui.control.Spinner
        mxSpinnerLabel                 matlab.ui.control.Label
        nzSpinner                      matlab.ui.control.Spinner
        nzSpinnerLabel                 matlab.ui.control.Label
        nySpinner                      matlab.ui.control.Spinner
        nySpinnerLabel                 matlab.ui.control.Label
        nxSpinner                      matlab.ui.control.Spinner
        nxSpinnerLabel                 matlab.ui.control.Label
        UniformGridCheckBox            matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        lamps   % 
        summary % content of the summary file
        summaryNotLoaded % check if the summary file loaded
        sl % line of characters 
        nx % Meshpoints in x-direction
        ny % Meshpoints in y-direction
        nz % Meshpoints in z-direction
        mx % number of slices in x direction
        my % number of slices in y direction
        mesh % mesh name
        vtk  % vtk mesh name
        file % summary file
        path % path of the summary file
        raw  % raw imported mesh

        taggedCrds % coordinates of the tagged vertices
        
        preshape

        compressedGrid        
    end
    
    methods (Access = private)
        function dispLine(app)
            disp(app.sl) ;
        end
        function writeGridSummary(app)
            preshape = app.preshape ;
            filename = sprintf("%s_preshape.mat",app.GridNameEditField.Value) ;
            fprintf("Writing preshape to disk...\n") ;
            save(sprintf("%s/%s",app.path,filename), "preshape",'-v7.3' );
            fprintf("Done!\n");

            app.summary.preshape=filename ;

            app.summary.nx = app.nx ;
            app.summary.ny = app.ny ;
            app.summary.nz = app.nz ;
            app.summary.mx = app.mx ;
            app.summary.my = app.my ;
            
            app.summary.comments = app.CommentsTextArea.Value ;
            app.summary.holeThreshold = app.HoleThresholdEditField.Value ;
            app.summary.calculatedAlpha = app.CalculatedAlphaEditField.Value ;
            app.summary.adjustedAlpha = app.AdjustedAlphaEditField.Value ;
            app.summary.tags= app.SelectedTagsEditField.Value ;
            app.summary.namedRegion = app.NameRegionEditField.Value ;
            app.summary.gridName = app.GridNameEditField.Value ;


            fid=fopen(sprintf("%s/%s.summary",app.path,app.GridNameEditField.Value),'w') ;
            fprintf(fid,jsonencode(app.summary)) ;
            fclose(fid) ;
        end

        function processSummary(app)
            if (isfield(app.summary,'preshape'))

                loadMesh(app) ;
                app.CommentsTextArea.Value =app.summary.comments ;
                app.HoleThresholdEditField.Value = app.summary.holeThreshold ;
                app.CalculatedAlphaEditField.Value = app.summary.calculatedAlpha ;
                app.AdjustedAlphaEditField.Value = app.summary.adjustedAlpha ;
                app.nxSpinner.Value = app.summary.nx ;
                app.nySpinner.Value = app.summary.ny ;
                app.nzSpinner.Value = app.summary.nz ;
                app.mxSpinner.Value = app.summary.mx ;
                app.mySpinner.Value = app.summary.my ;
                app.SelectedTagsEditField.Value = app.summary.tags ;
                app.NameRegionEditField.Value = app.summary.namedRegion ;
                
                filterTaggedRegions(app) ;

                fprintf("Loading preshape from %s...\n",app.summary.preshape) ;
                s = load(sprintf('%s/%s',app.path,app.summary.preshape)) ;
                fprintf("Done!\n");
                
                setSubsequentLampColors(app,app.MeshLoadedLamp,'green');

                app.preshape = s.preshape ;
                app.GridNameEditField.Value = app.summary.gridName ;
            end
        end
        function writeSummary(app)
            fid=fopen(sprintf("%s/%s",app.path,app.file),'w') ;
            fprintf(fid,jsonencode(app.summary)) ;
            fclose(fid) ;
        end 
        function loadCarp(app)
            disp(app.sl) ;
            fprintf("Starting to import mesh %s\n", app.mesh) ;
            fprintf("Reading the point file %s.pts\n",app.mesh) ;
            pointFileName = sprintf("%s/%s.pts",app.path,app.mesh) ;
            fid=fopen(pointFileName,'r') ;

            % READ NUMBER OF POINTS --------------------------------------
            noPoints = fscanf(fid,"%d",1) ;

            % READ 3 COORDINATE PER POINT --------------------------------
            ocrd =fscanf(fid,"%f",3*noPoints);

            % CLOSE THE FILE ---------------------------------------------
            fclose(fid) ;

            disp("Done!") ;

            % RESHAPE THE IMPORTED COORDINATES ---------------------------
            crds = reshape(ocrd,3,noPoints)' ;
    
            % EXTRACT X,Y,Z COORDINATES ----------------------------------
            crd.noPoints = noPoints ;
            crd.xyz = crds ;
            crd.x = crds(:,1) ;
            crd.y = crds(:,2) ;
            crd.z = crds(:,3) ;

            app.raw.crds = crd ;

            % READING ELEMENTS ===========================================
            elemFileName = sprintf("%s/%s.elem",app.path, app.mesh) ;

            fprintf("Reading the elem file %s.elem\n", app.mesh) ;
            fid = fopen(elemFileName,'r') ;
            noElems = fscanf(fid,"%d") ;
            fclose(fid) ;
            disp("Done!") ;

            elemFileName = sprintf("%s/%s.elem.proc",app.path,app.mesh) ;
            fprintf("Reading proc file %s.elem.proc\n",app.mesh);
            
            fid = fopen(elemFileName,'r') ;
            elemsRaw = fscanf(fid,"%d",noElems*5) ;
            fclose(fid) ;
            
            disp("Done!") ;
            
            disp("Processing elements...")
            elemsProc = reshape(elemsRaw,5,noElems)' ;
            
            % exporting the elems info
            app.raw.elems.noElems = noElems ;
            app.raw.elems.cnn = elemsProc(:,1:4)+1 ;
            app.raw.elems.tag = elemsProc(:,5) ;
            app.raw.elems.tags = unique(app.raw.elems.tag,'sorted') ;
            disp("Done!");

            raw = app.raw ;

            disp("Writing matlab mesh file...") ;
            save(sprintf("%s/%s.mat",app.path,app.mesh),"raw","-v7.3") ;
            app.summary.raw = sprintf("%s.mat",app.mesh) ;
            writeSummary(app) ;
            disp("Done!")
        end % END OF loadCarp

        function loadMatMesh(app)
            fprintf("Loading mesh from %s\n",app.summary.raw) ;
            matFileContent = load(sprintf("%s/%s",app.path,app.summary.raw));
            app.raw = matFileContent.raw ;
            disp("Done!")
        end

        function loadMesh(app)
            if (isfield(app.summary,'raw'))
                loadMatMesh(app) ;
            else
                loadCarp(app) ;
            end
            app.MeshLoadedLamp.Color = 'green' ;
            app.AvilableTagsEditField.Value =num2str(app.raw.elems.tags');
        end
        
        %% setSubsequentLampColors
        function setSubsequentLampColors(app,curr,color)   
            if(size(curr.Next,1)==1)
                next = curr.Next ;
                next.Color = color ;
                setSubsequentLampColors(app,next,color) ;
            end
        end

        %% applyTags
        function applyTags(app)
            tags=str2num(app.SelectedTagsEditField.Value) ;
            imesh=app.raw ;
            dispLine(app) ;
            disp("Getting tagged vertices") ;
            taggedIndices = zeros(imesh.crds.noPoints,1) ;
            tagged = (imesh.elems.tag == tags(1)) ;
            for i=2:size(tags,2)
                tagged = tagged | (imesh.elems.tag == tags(i)) ;
            end
            taggedIndices(imesh.elems.cnn(tagged,:)) = 1;
            taggedIndices = (taggedIndices==1) ;
            
            % export the information required
            crds.noPoints = sum(taggedIndices) ;
            crds.xyz = imesh.crds.xyz(taggedIndices,:) ;
            crds.x = imesh.crds.x(taggedIndices) ;
            crds.y = imesh.crds.y(taggedIndices) ;
            crds.z = imesh.crds.z(taggedIndices) ;
            app.taggedCrds = crds ;
            disp("Done!") ;
            
        end
        
        %% scaleIntoUnitCube
        function scaleIntoUnitCube(app)
            ocrds = app.taggedCrds ;
            dispLine(app) ;
            disp("Scaling coordinates into a unit cube from 0-1...") ;
            minCrds = min(ocrds.xyz) ;
            maxCrds = max(ocrds.xyz) ;
            
            geoLength = (maxCrds-minCrds) ;
            maxLength = max(geoLength);
            
            xyz = ocrds.xyz-minCrds ;
            xyz = xyz/maxLength ;
            
            ncrds.noPoints = ocrds.noPoints ;
            ncrds.xyz = xyz ;
            ncrds.x   = xyz(:,1) ;
            ncrds.y   = xyz(:,2) ;
            ncrds.z   = xyz(:,3) ;
            ncrds.length = geoLength ;

            app.taggedCrds = ncrds ;
            disp("Done!") ;
        end

        %% filterTaggdRegions 
        function filterTaggedRegions(app)
            applyTags(app) ;
            scaleIntoUnitCube(app) ;

            app.FilterringStatusLamp.Color = 'green' ;
            setSubsequentLampColors(app,app.FilterringStatusLamp,'yellow');
        end

        
        %% createAndCompressGrid
        function [oArg] =createAndCompressGrid(app)
            nx = app.nx ;
            ny = app.ny ;
            mx = app.mx ;
            my = app.my ;
            nz = app.nz ;
            
            iShape = app.preshape ;
            
            dispLine(app) ;
            disp("Generating the grid...")
                
            % gerating grid 
            x = zeros(nx*mx,ny*my) ;
            y = zeros(nx*mx,ny*my) ;
            z = zeros(nx*mx,ny*my) ;
            
            % discretization size 
            ds = [1 1 1]./[nx,ny,nz] ; 
            for I=1:(nx*mx)
                for J=1:(ny*my)
                    i=mod(I-1,nx) ;
                    si=mx-(floor((I-1)/nx)) ;
                    j=ny-mod(J-1,ny) ;
                    sj=(my-1)-floor((J-1)/ny);
                    %i=nx-i;
                    %j=ny-j ;
                    k=nz-(mx*sj+si);
            
                    x(I,J) = i*ds(1) ;
                    y(I,J) = j*ds(2) ;
                    z(I,J) = k*ds(3) ;
                end
            end
            disp("Done") ;
            %
            disp("Finding the in-tissue vertices...")
            flatMatrix = inShape(iShape,x,y,z);
            
            disp("Done!");


            disp('Compressing the data structure...') ;
            
            width = app.mx*app.nx ;
            height= app.my*app.ny ;
            noDomainVertices = sum(sum(flatMatrix)) ;
            
            compressedSize = ceil(sqrt(noDomainVertices )) ;
            
            fullTexelIndex          = zeros(compressedSize*compressedSize*4,1) ;
            compressedTexelIndex    = zeros(width*height*4,1) ;
            
            % coordinates of domain points
            xi = zeros( noDomainVertices, 1) ;
            xj = zeros( noDomainVertices, 1) ;
            xk = zeros( noDomainVertices, 1) ;
            
            num = 0 ;
            for j=1:height
                for i=1:width
                    indx = (i-1)+(j-1)*width ;
                    
                    % notice how the j index in the original flat matrix need to be
                    % flipped. This is due to the fact that images are filled from the
                    % top to the buttom and if the data matrix need to be filled from
                    % buttom to the top this should be carefully applied.
                    if ( flatMatrix(i,height+1-j) )
                        jj = floor(num/compressedSize) ;
                        ii = mod(num,compressedSize) ;
                        
                        nindx = ii + jj*compressedSize ;
                        
                        compressedTexelIndex(indx*4+1)=ii ;
                        compressedTexelIndex(indx*4+2)=jj ;
                        compressedTexelIndex(indx*4+3)=1 ;
                        compressedTexelIndex(indx*4+4)=1 ;
                        
                        fullTexelIndex(nindx*4 + 1) = i-1 ;
                        fullTexelIndex(nindx*4 + 2) = j-1 ;
                        fullTexelIndex(nindx*4 + 3) = 1 ;
                        fullTexelIndex(nindx*4 + 4) = 1 ;
                        num = num+1 ;
                        
                        % coordinate of the compressed point
                        xi(num) = mod(i-1, app.nx) ;
                        si = floor((i-1)/app.nx) ;
                        xj(num) = mod(j-1, app.ny) ;
                        sj = (app.my-1)-floor((j-1)/app.ny);
                        xk(num) = si + app.mx*sj ;
                    end
                end
            end
            
            disp('Done!');
            
            %--------------------------------------------------------------------------
            P = [ xi xj xk ] ;
                        
            %--------------------------------------------------------------------------
            oArg.nx = app.nx ;
            oArg.ny = app.ny ;
            oArg.nz = app.nz ;
            oArg.mx = app.mx ;
            oArg.my = app.my ;
            
            oArg.P                      = P ;
            %oArg.alphaShape             = shp ;
            oArg.full_width             = width ;
            oArg.full_height            = height ;
            oArg.flatMatrix             = flatMatrix ;
            oArg.noDomainVertices       = noDomainVertices ;
            
            oArg.comp_width             = compressedSize ;
            oArg.comp_height            = compressedSize ;
            
            oArg.noAboveThreshold       = noDomainVertices ;
            oArg.compressedSize         = compressedSize ;
            
            oArg.compressedTexelIndex   = compressedTexelIndex ;
            oArg.fullTexelIndex         = fullTexelIndex ;
            
            app.compressedGrid = oArg ;
        end

        %% createAlphaShape
        function [oArg] = createAlphaShape(app)
            out_compressed = app.compressedGrid ;
            dispLine(app) ;
            disp 'Generating alphaShape' ;
            shp = alphaShape(out_compressed.P,1,'HoleThreshold',.2);
            disp 'Done!' ;
            
            disp 'Generating boundary facets...' ;
            [tri,xyz] = shp.boundaryFacets() ;
            disp 'Done' ;
            
            %trisurf(tri,xyz(:,1),xyz(:,2),xyz(:,3),...
            %    'FaceColor','cyan','FaceAlpha',0.3) ;
            
            mx = out_compressed.mx ;
            my = out_compressed.my ;
            nx = out_compressed.nx ;
            ny = out_compressed.ny ;
            noTriangles = size(tri, 1) ;
            noNodes     = noTriangles*3 ; % three nodes per triangle
            noIndices   = noTriangles*6 ; % two index per node
            indices     = zeros( noIndices,1 ) ;
            for tNo =1:noTriangles 
                for nNo=1:3
                    indx = tri(tNo,nNo) ;
                    i = xyz(indx,1) ;
                    j = xyz(indx,2) ;
                    k = xyz(indx,3) ;
                    si = mod(k, mx) ;
                    sj = floor( k/mx) ;
                    I = nx*si+i ;
                    J = (my-1-sj)*ny + j ;
                    
                    indexNo = (tNo-1)*6 + (nNo-1)*2 ;
                    indices(indexNo+1) = I ;
                    indices(indexNo+2) = J ;
               
                end
            end
            
            if(isfield(out_compressed,"alphaCoef"))
            %if (exists("out_compressed.alphaCoef"))
                disp("Scaling Shape's alpha")
                shp.Alpha = out_compressed.alphaCoef*shp.Alpha ;
                disp("Done");
            end
            oArg = out_compressed ;
            
            oArg.alphaShape = shp ;
            
            oArg.boundaryFacets.triangles  = tri ;
            oArg.boundaryFacets.xyz        = xyz ;
            oArg.boundaryFacets.indices    = indices ;
            oArg.boundaryFacets.noTriangles= noTriangles ;
            oArg.boundaryFacets.noNodes    = noNodes ;

            app.compressedGrid = oArg ;
        end
        %% writeStructureToDisk
        function writeStructureToDisk(app)
            output_info = app.compressedGrid ;
            output_info.filename = app.GridNameEditField.Value ;
            strct.nx                    = output_info.nx ;
            strct.ny                    = output_info.ny ;
            strct.mx                    = output_info.mx ;
            strct.my                    = output_info.my ;
            %strct.threshold             = output_info.threshold ;
            strct.full_width            = output_info.full_width ;
            strct.full_height           = output_info.full_height ;
            strct.comp_width            = output_info.comp_width ;
            strct.comp_height           = output_info.comp_height ;
            strct.fullTexelIndex        = output_info.fullTexelIndex ;
            strct.compressedTexelIndex  = output_info.compressedTexelIndex ;
            
            bf = output_info.boundaryFacets ;
            strct.boundaryFacets.noTriangles = bf.noTriangles ;
            strct.boundaryFacets.noNodes     = bf.noNodes ;
            strct.boundaryFacets.indices     = bf.indices ;
            
            fprintf('Creating the %s.json file...\n', output_info.filename) ;
            strct_json = jsonencode(strct);
            
            fid = fopen(sprintf("%s/%s.json",app.path, output_info.filename),'w') ;
            fprintf(fid, strct_json);
            fclose(fid);
            disp('Done!') ;
            
            fprintf('Creating the %s.png file...\n', output_info.filename) ;
            imwrite(output_info.flatMatrix',sprintf("%s/%s.png",app.path, output_info.filename));
            
            disp('Done!') ;
        end

        %% suggestGridName
        function suggestGridName(app)
            gridName =sprintf("%s-%dx%dx%d_%s_grid",app.mesh,app.nx,app.ny,app.nz,app.NameRegionEditField.Value);
            app.GridNameEditField.Value = gridName ;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.summaryNotLoaded = true ;
            
            % set lamp sequence
            app.SummaryLoadedLamp.addprop("Next") ;
            app.SummaryLoadedLamp.Next = app.MeshLoadedLamp ;

            app.MeshLoadedLamp.addprop("Next") ;
            app.MeshLoadedLamp.Next = app.FilterringStatusLamp ;

            app.FilterringStatusLamp.addprop("Next") ;
            app.FilterringStatusLamp.Next = app.PrealphaShapestatusLamp ;

            app.PrealphaShapestatusLamp.addprop("Next");
            app.PrealphaShapestatusLamp.Next = app.GenerateCompressLamp ;

            app.GenerateCompressLamp.addprop("Next") ;
            app.GenerateCompressLamp.Next = app.CreateAlphashapeLamp ;

            app.CreateAlphashapeLamp.addprop("Next") ;
            app.CreateAlphashapeLamp.Next = app.SavetoDiskLamp ;

            app.SavetoDiskLamp.addprop("Next") ;
            
            app.sl = "" ;
            for i=1:60
                app.sl=sprintf("%s-",app.sl) ;
            end
            app.nx = app.nxSpinner.Value ;
            app.ny = app.nySpinner.Value ;
            app.nz = app.nzSpinner.Value ;
            app.mx = app.mxSpinner.Value ;
            app.my = app.mySpinner.Value ;

        end

        % Value changed function: nxSpinner
        function nxSpinnerValueChanged(app, event)
            value = app.nxSpinner.Value;
            app.nx = value ;
            if (app.UniformGridCheckBox.Value)
                app.ny = value ;
                app.nz = value ;
                app.nySpinner.Value = value ;
                app.nzSpinner.Value = value ;
            end
            app.my= app.nz/app.mx ;
            app.mySpinner.Value = app.my ;

            suggestGridName(app) ;
        end

        % Button pushed function: OpenSummaryFileButton
        function OpenSummaryFileButtonPushed(app, event)
            if (app.summaryNotLoaded)
                app.SummaryLoadedLamp.Color = [1.,0.4,0.16];
            end
            [app.file,app.path] = uigetfile('*.summary');
            if isequal(app.file,0)
                if (app.summaryNotLoaded)
                    app.SummaryLoadedLamp.Color = 'red' ;
                end
                disp('User selected Cancel') ;                
            else
                %disp(['User selected ', fullfile(app.path,app.file)]);
                fid=fopen(fullfile(app.path,app.file),'r') ;
                str=char(fread(fid,inf)');
                fclose(fid) ;
                val=jsondecode(str) ;
                

                app.summary = val ;
                app.mesh = val.mesh ;
                app.vtk  = val.vtk ;
                app.summaryNotLoaded = false ;
                app.SummaryLoadedLamp.Color = 'green';
                setSubsequentLampColors(app,app.SummaryLoadedLamp,'yellow');
                suggestGridName(app) ;
                
                processSummary(app) ;
                %loadCarp(app) ;  

                % when the file is opened check if mat file exists and then
                % load that. Otherwise load the carp format
            end
        end

        % Button pushed function: LoadMeshButton
        function LoadMeshButtonPushed(app, event)
            app.MeshLoadedLamp.Color = [1,0.6,0.14] ;
            loadMesh(app) ;
        end

        % Button pushed function: FilterlabelsButton
        function FilterlabelsButtonPushed(app, event)
            filterTaggedRegions(app);
        end

        % Button pushed function: CalculateAlphaShapeButton
        function CalculateAlphaShapeButtonPushed(app, event)
            app.PrealphaShapestatusLamp.Color = [1 .41 .16] ;
            disp("Calculating pre-alphaShape") ;
            crds = app.taggedCrds ;
            ev   = app.DownsampleEditField.Value ;
            ht   = app.HoleThresholdEditField.Value ;
            app.preshape = alphaShape(crds.x(1:ev:end),crds.y(1:ev:end),crds.z(1:ev:end),"HoleThreshold",ht) ;

            app.CalculatedAlphaEditField.Value = app.preshape.Alpha ;
            app.AdjustedAlphaEditField.Value = app.preshape.Alpha ;
            app.PrealphaShapestatusLamp.Color = 'green' ;
            setSubsequentLampColors(app,app.PrealphaShapestatusLamp,'yellow') ;
            disp("Done!");

        end

        % Button pushed function: AdjustAlphaButton
        function AdjustAlphaButtonPushed(app, event)
            disp("Adjusting alpha value of the pre-alphaShape!");
            newAlpha = max(app.CalculatedAlphaEditField.Value, app.AdjustedAlphaEditField.Value);
            app.preshape.Alpha = newAlpha ;
            app.PrealphaShapestatusLamp.Color = 'green' ;
            setSubsequentLampColors(app,app.PrealphaShapestatusLamp,'yellow') ;
            disp("Done!");
        end

        % Value changed function: AdjustedAlphaEditField
        function AdjustedAlphaEditFieldValueChanged(app, event)
            value = app.AdjustedAlphaEditField.Value;
            app.PrealphaShapestatusLamp.Color = "yellow" ;
            setSubsequentLampColors(app,app.PrealphaShapestatusLamp,'yellow') ;
        end

        % Value changed function: HoleThresholdEditField
        function HoleThresholdEditFieldValueChanged(app, event)
            value = app.HoleThresholdEditField.Value;
            app.PrealphaShapestatusLamp.Color = "yellow" ;
            setSubsequentLampColors(app,app.PrealphaShapestatusLamp,'yellow') ;
        end

        % Value changed function: NameRegionEditField
        function NameRegionEditFieldValueChanged(app, event)
            value = app.NameRegionEditField.Value;
            suggestGridName(app) ;
        end

        % Value changed function: nySpinner
        function nySpinnerValueChanged(app, event)
            value = app.nySpinner.Value;
            suggestGridName(app) ;
        end

        % Value changed function: nzSpinner
        function nzSpinnerValueChanged(app, event)
            value = app.nzSpinner.Value;
            suggestGridName(app) ;
        end

        % Button pushed function: GenerateCompressandSaveButton
        function GenerateCompressandSaveButtonPushed(app, event)
            app.GenerateCompressLamp.Color = [1 .41 .16] ;
            createAndCompressGrid(app) ;
            app.GenerateCompressLamp.Color = 'green' ;

            app.CreateAlphashapeLamp.Color = [1 .41 .16] ;
            createAlphaShape(app) ;
            app.CreateAlphashapeLamp.Color = 'green' ;

            app.SavetoDiskLamp.Color = [1 .41 .16] ;
            writeStructureToDisk(app) ;
            app.SavetoDiskLamp.Color = 'green' ;

            writeGridSummary(app) ;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GenerateCartesianGridPanel = uipanel(app.UIFigure);
            app.GenerateCartesianGridPanel.Title = '4) Generate Cartesian Grid';
            app.UniformGridCheckBox = uicheckbox(app.GenerateCartesianGridPanel);
            app.UniformGridCheckBox.Text = 'Uniform Grid';
            app.UniformGridCheckBox.Value = true;
            app.nxSpinnerLabel = uilabel(app.GenerateCartesianGridPanel);
            app.nxSpinnerLabel.Text = 'nx';
            app.nxSpinner = uispinner(app.GenerateCartesianGridPanel);
            app.nxSpinner.Step = 16;
            app.nxSpinner.Limits = [32 Inf];
            app.nxSpinner.ValueChangedFcn = createCallbackFcn(app, @nxSpinnerValueChanged, true);
            app.nxSpinner.Value = 128;
            app.nySpinnerLabel = uilabel(app.GenerateCartesianGridPanel);
            app.nySpinnerLabel.Text = 'ny';
            app.nySpinner = uispinner(app.GenerateCartesianGridPanel);
            app.nySpinner.Step = 16;
            app.nySpinner.Limits = [32 Inf];
            app.nySpinner.ValueChangedFcn = createCallbackFcn(app, @nySpinnerValueChanged, true);
            app.nySpinner.Value = 128;
            app.nzSpinnerLabel = uilabel(app.GenerateCartesianGridPanel);
            app.nzSpinnerLabel.Text = 'nz';
            app.nzSpinner = uispinner(app.GenerateCartesianGridPanel);
            app.nzSpinner.Step = 16;
            app.nzSpinner.Limits = [32 Inf];
            app.nzSpinner.ValueChangedFcn = createCallbackFcn(app, @nzSpinnerValueChanged, true);
            app.nzSpinner.Value = 128;
            app.mxSpinnerLabel = uilabel(app.GenerateCartesianGridPanel);
            app.mxSpinnerLabel.Text = 'mx';
            app.mxSpinner = uispinner(app.GenerateCartesianGridPanel);
            app.mxSpinner.Value = 16;
            app.mySpinnerLabel = uilabel(app.GenerateCartesianGridPanel);
            app.mySpinnerLabel.Text = 'my';
            app.mySpinner = uispinner(app.GenerateCartesianGridPanel);
            app.mySpinner.Value = 8;
            app.GridNameEditFieldLabel = uilabel(app.GenerateCartesianGridPanel);
            app.GridNameEditFieldLabel.Text = 'Grid Name';
            app.GridNameEditField = uieditfield(app.GenerateCartesianGridPanel, 'text');
            app.GenerateCompressLampLabel = uilabel(app.GenerateCartesianGridPanel);
            app.GenerateCompressLampLabel.Text = 'Generate & Compress';
            app.GenerateCompressLamp = uilamp(app.GenerateCartesianGridPanel);
            app.CreateAlphashapeLampLabel = uilabel(app.GenerateCartesianGridPanel);
            app.CreateAlphashapeLampLabel.Text = 'Create Alphashape';
            app.CreateAlphashapeLamp = uilamp(app.GenerateCartesianGridPanel);
            app.SavetoDiskLampLabel = uilabel(app.GenerateCartesianGridPanel);
            app.SavetoDiskLampLabel.Text = 'Save to Disk';
            app.SavetoDiskLamp = uilamp(app.GenerateCartesianGridPanel);
            app.GenerateCompressandSaveButton = uibutton(app.GenerateCartesianGridPanel, 'push');
            app.GenerateCompressandSaveButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCompressandSaveButtonPushed, true);
            app.GenerateCompressandSaveButton.Text = 'Generate, Compress, and Save';
            app.CalculatePrealphaShapePanel = uipanel(app.UIFigure);
            app.CalculatePrealphaShapePanel.Title = '3) Calculate Pre-alphaShape';
            app.CalculatedAlphaEditFieldLabel = uilabel(app.CalculatePrealphaShapePanel);
            app.CalculatedAlphaEditFieldLabel.Text = 'Calculated Alpha';
            app.CalculatedAlphaEditField = uieditfield(app.CalculatePrealphaShapePanel, 'numeric');
            app.AdjustedAlphaEditFieldLabel = uilabel(app.CalculatePrealphaShapePanel);
            app.AdjustedAlphaEditFieldLabel.Text = 'Adjusted Alpha';
            app.AdjustedAlphaEditField = uieditfield(app.CalculatePrealphaShapePanel, 'numeric');
            app.AdjustedAlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AdjustedAlphaEditFieldValueChanged, true);
            app.HoleThresholdEditFieldLabel = uilabel(app.CalculatePrealphaShapePanel);
            app.HoleThresholdEditFieldLabel.Text = 'Hole Threshold';
            app.HoleThresholdEditField = uieditfield(app.CalculatePrealphaShapePanel, 'numeric');
            app.HoleThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @HoleThresholdEditFieldValueChanged, true);
            app.AdjustAlphaButton = uibutton(app.CalculatePrealphaShapePanel, 'push');
            app.AdjustAlphaButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustAlphaButtonPushed, true);
            app.AdjustAlphaButton.Text = {'Adjust'; 'Alpha'};
            app.CalculateAlphaShapeButton = uibutton(app.CalculatePrealphaShapePanel, 'push');
            app.CalculateAlphaShapeButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateAlphaShapeButtonPushed, true);
            app.CalculateAlphaShapeButton.Text = {'Calculate'; 'AlphaShape'};
            app.PrealphaShapestatusLampLabel = uilabel(app.CalculatePrealphaShapePanel);
            app.PrealphaShapestatusLampLabel.Text = 'Pre-alphaShape status';
            app.PrealphaShapestatusLamp = uilamp(app.CalculatePrealphaShapePanel);
            app.DownsampleEditFieldLabel = uilabel(app.CalculatePrealphaShapePanel);
            app.DownsampleEditFieldLabel.Text = 'Downsample';
            app.DownsampleEditField = uieditfield(app.CalculatePrealphaShapePanel, 'numeric');
            app.DownsampleEditField.Limits = [1 Inf];
            app.DownsampleEditField.RoundFractionalValues = 'on';
            app.DownsampleEditField.ValueDisplayFormat = '%d';
            app.DownsampleEditField.Tooltip = {'Downsampling rate: 1 represebts original rate and any integer n, represents sampling every n''th point.'};
            app.DownsampleEditField.Value = 1;
            app.LoadMeshPanel = uipanel(app.UIFigure);
            app.LoadMeshPanel.Title = '1) Load Mesh';
            app.OpenSummaryFileButton = uibutton(app.LoadMeshPanel, 'push');
            app.OpenSummaryFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSummaryFileButtonPushed, true);
            app.OpenSummaryFileButton.Text = 'Open Summary File';
            app.CommentsTextAreaLabel = uilabel(app.LoadMeshPanel);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.LoadMeshPanel);
            app.LoadMeshButton = uibutton(app.LoadMeshPanel, 'push');
            app.LoadMeshButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMeshButtonPushed, true);
            app.LoadMeshButton.Text = 'Load Mesh';
            app.SummaryLoadedLamp = uilamp(app.LoadMeshPanel);
            app.SummaryLoadedLamp.Tooltip = {'Yellow: not selected'; 'Green: Summary file successfully loaded'; 'Red: There was an error opening the summary file.'};
            app.MeshLoadedLamp = uilamp(app.LoadMeshPanel);
            app.MeshLoadedLamp.Tooltip = {'Yellow: Mesh not loaded.'; 'Orange: Mesh file being loaded.'; 'Green: Mesh file successfully loaded.'; 'Red: There was an error loading the mesh.'};
            app.ChooseLabelstoProcessPanel = uipanel(app.UIFigure);
            app.ChooseLabelstoProcessPanel.Title = '2) Choose Labels to Process';
            app.SelectedLabelsEditFieldLabel = uilabel(app.ChooseLabelstoProcessPanel);
            app.SelectedLabelsEditFieldLabel.Text = 'Selected Tags';
            app.SelectedTagsEditField = uieditfield(app.ChooseLabelstoProcessPanel, 'text');
            app.SelectedTagsEditField.Tooltip = {'Enter your desired tags. Separate the tags by white space.'};
            app.NameRegionEditFieldLabel = uilabel(app.ChooseLabelstoProcessPanel);
            app.NameRegionEditFieldLabel.Text = 'Name Region';
            app.NameRegionEditField = uieditfield(app.ChooseLabelstoProcessPanel, 'text');
            app.NameRegionEditField.ValueChangedFcn = createCallbackFcn(app, @NameRegionEditFieldValueChanged, true);
            app.NameRegionEditField.Tooltip = {'The region name will be inlcuded in the grid name.'};
            app.FilterlabelsButton = uibutton(app.ChooseLabelstoProcessPanel, 'push');
            app.FilterlabelsButton.ButtonPushedFcn = createCallbackFcn(app, @FilterlabelsButtonPushed, true);
            app.FilterlabelsButton.Text = 'Filter labels';
            app.AvilableTagsEditFieldLabel = uilabel(app.ChooseLabelstoProcessPanel);
            app.AvilableTagsEditFieldLabel.Text = 'Avilable Tags';
            app.AvilableTagsEditField = uieditfield(app.ChooseLabelstoProcessPanel, 'text');
            app.AvilableTagsEditField.Editable = 'off';
            app.FilterringStatusLampLabel = uilabel(app.ChooseLabelstoProcessPanel);
            app.FilterringStatusLampLabel.Text = 'Filterring Status';
            app.FilterringStatusLamp = uilamp(app.ChooseLabelstoProcessPanel);
            app.Carp2CartesianMeshConversionToolLabel = uilabel(app.UIFigure);
            app.Carp2CartesianMeshConversionToolLabel.Text = '  Carp2Cartesian: Mesh Conversion Tool';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Carp2Cartesian

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end